classdef DataVisualizerPremiumPlus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        SelectLabel              matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        File2Button              matlab.ui.control.RadioButton
        File1Button              matlab.ui.control.RadioButton
        FieldSearch              matlab.ui.control.EditField
        SearchLabel              matlab.ui.control.Label
        LoadFile1                matlab.ui.control.Button
        LoadFile2                matlab.ui.control.Button
        UITableAll               matlab.ui.control.Table
        UIAxesPreview            matlab.ui.control.UIAxes
        PanelButtons             matlab.ui.container.Panel
        BackButton               matlab.ui.control.Button
        VisibileLabel            matlab.ui.control.Label
        CheckBoxFile1            matlab.ui.control.CheckBox
        CheckBoxFile2            matlab.ui.control.CheckBox
        Label_2                  matlab.ui.control.Label
        DeltaEditFieldButtons    matlab.ui.control.NumericEditField
        YEditFieldButtons        matlab.ui.control.NumericEditField
        YEditField_2Label        matlab.ui.control.Label
        UITableButtonsPlot       matlab.ui.control.Table
        XEditFieldButtons        matlab.ui.control.NumericEditField
        XEditField_2Label        matlab.ui.control.Label
        LabelBrakeButtons        matlab.ui.control.Label
        LabelAcceleratorButtons  matlab.ui.control.Label
        UIPedalButtons           matlab.ui.control.UIAxes
        UIAxesButtons            matlab.ui.control.UIAxes
        PanelMain                matlab.ui.container.Panel
        DeltaEditField           matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditField_2Label_2      matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        Settings                 matlab.ui.control.Button
        LabelSpeed               matlab.ui.control.Label
        LabelRPM                 matlab.ui.control.Label
        LabelSOC                 matlab.ui.control.Label
        LabelSteer               matlab.ui.control.Label
        Steer                    matlab.ui.control.Image
        MotoreButton             matlab.ui.control.Button
        BatteriaButton           matlab.ui.control.Button
        AnalisiFrenataButton     matlab.ui.control.Button
        AnalisiCurvaeAmmoButton  matlab.ui.control.Button
        UITableLap               matlab.ui.control.Table
        AnalisiGuidaButton       matlab.ui.control.Button
        UITablePlot              matlab.ui.control.Table
        ClearGraphButton         matlab.ui.control.Button
        LabelBrake               matlab.ui.control.Label
        LabelAccelerator         matlab.ui.control.Label
        UIPedal                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        UITrack                  matlab.ui.control.UIAxes
        UIG_Force                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        signalFile1                 % Array della strcut dei segnali caricati dal file 1
        signalFile2                 % Array della struct dei segnali caricati dal file 2
        signalPlotFile1             % Array dei segnali del file 1 plottati nel grafico (nel formato ritornato dalla funzione plot)
        signalPlotFile2             % Array dei segnali del file 2 plottati nel grafico (nel formato ritornato dalla funzione plot)
        signalTableAllFile1         % Array dei segnali del file 1 in formato tabella ovvero per ogni riga [name, plot, min, max, unit, gain, offset]
        signalTableAllFile2         % Array dei segnali del file 2 in formato tabella ovvero per ogni riga [name, plot, min, max, unit, gain, offset]
        signalTable                 % Array dei segnali presenti nella tabella del menù in quel momento in formato tabella
        signalTablePlot             % Array dei segnali presenti nella tabella della pagina principale in quel momento in formato tabella
        signalPlotPreview           % Segnale plottato nel grafico di anteprima
        NAME_COLUMN_NUMBER = 1;     % Numero che rappresenta la colonna dei nomi
        PLOT_COLUMN_NUMBER = 2;     % Numero che rappresenta la colonna plot
        MIN_COLUMN_NUMBER = 3;      % Numero che rappresenta la colonna min
        MAX_COLUMN_NUMBER = 4;      % Numero che rappresenta la colonna max
        UNIT_OF_MEASURE_NUMBER = 5; % Numero che rappresenta la colonna unità di misura
        GAIN_COLUMN_NUMBER = 6;     % Numero che rappresenta la colonna gain
        OFFSET_COLUMN_NUMBER = 7;   % Numero che rappresenta la colonna offset
        nameFile1 = "-";            % Nome del file caricato nel primo bottone  
        nameFile2 = "-";            % Nome del file caricato nel secondo bottone
        nameSelected                % Nome del segnale selezionato nella tabella dei segnali plottati
        nameSelectedButtons         % Nome del segnale selezionato nella tabella dei segnali plottati nel pannello dei bottoni
        fileSelected                % Nome del file del segnale selezionato nella tabella dei segnali plottati
        fileSelectedButtons         % Nome del file del segnale selezionato nella tabella dei segnali plottati nel pannello dei bottoni
        x = 0.000;                  % Valore della X cercata
        xButtons = 0.000;           % Valore della X cercata nel pannello dei bottoni
        y = 0.000;                  % Valore della Y corrispondente alla X
        yButtons = 0.000;           % Valore della Y corrispondente alla X nel pannello dei bottoni
        currentXLine                % Variabile che salva il risultato della funzione plot per la retta verticale
        currentXLineButtons         % Variabile che salva il risultato della funzione plot per la retta verticale nel pannello dei bottoni
        rectBrake                   % Variabile che rappresenta il risultato della funzione rect per il pedale del freno
        rectBrakeButtons            % Variabile che rappresenta il risultato della funzione rect per il pedale del freno nel pannello dei bottoni
        rectAccelerator             % Variabile che rappresenta il risultato della funzione rect per il pedale dell'acceleratore
        rectAcceleratorButtons      % Variabile che rappresenta il risultato della funzione rect per il pedale dell'acceleratore nel pannello dei bottoni
        detectedTime  = [20, 80, 140, 200, 260, 322, 380, 440, 500, 560, 620, 680,740, 800, 860, 920];
        signalTableLap              % Array dei segnali suddivisi in giri che compongono la tabella, in formato tabella
        splitSignalLap              % Array dei segnali che sono stati suddivisi in giri, contiene per ogni cella una struct {Name, Laps} e a sua volta Laps è una struct che contiene ogni valore di ogni giro {X, Y, min, max, lapTime}
        signalPlotLap               % Array dei segnali divisi in lap plotatti nel grafico (nel formato ritornato dalla funzione plot)
        signalTableButtons          % Array dei segnali plottati nell'axes in formato tabella
        signalPlotButtons           % Array dei segnali plottati in formato plot
        buttonClicked               % variabile per salvarsi quale tasto è stato schiacciato
    end
    
    methods (Access = private)
        
        function h = circle(app, x, y, r)
            d = r*2;
            px = x-r;
            py = y-r;
            h = rectangle(app.UIG_Force, 'Position', [px py d d], 'Curvature', [1,1]);
            return
        end

        %Funzione che stampa i valori dei segnali nella tabella
        %parametri: app, signalName: nome del segnale cercato, signalFile: array contenente i segnali del file 1 o 2
        function changeDataTableAll(app, signalName, signal)
            app.signalTable = [];                  % Rimuove tutti i valori nella tabella
            s = size(signal);                       % Prende il numero di segnali, size() restituisce un array con valori uguale alle righe, colonne
            for i=1:1:s(1)
                 if contains(lower(signal{i, app.NAME_COLUMN_NUMBER}), lower(signalName))
                     newRow = {signal{i, app.NAME_COLUMN_NUMBER}, signal{i, app.PLOT_COLUMN_NUMBER}, signal{i, app.MIN_COLUMN_NUMBER}, signal{i, app.MAX_COLUMN_NUMBER}, signal{i, app.UNIT_OF_MEASURE_NUMBER}}; %Definisco com'è la riga
                     app.signalTable = [app.signalTable; newRow];   % La nuova riga viene aggiunta all'array dei segnali stampati nella tabella
                 end
            end
            app.UITableAll.Data = app.signalTable;               % Cambia i dati nella tabella
        end
        
        %Funzione che stampa i segnali scelti nella tabella completa nella
        %tabella sotto l'UIAxes,
        function changeDataTablePlot(app)
            s = size(app.signalTableAllFile1);
            for i=1:s(1) %Scorro tutti i segnali del file 1
                nameTableAll = app.signalTableAllFile1{i, app.NAME_COLUMN_NUMBER};
                if app.signalTableAllFile1{i, app.PLOT_COLUMN_NUMBER} == true %controllo quelli che ha spuntato
                    sizePlot = size(app.signalTablePlot); % dimensione array tabella segnali plottati
                    control = false;
                    for j=1:sizePlot(1) %Scorro tutti i segnali presenti nella tabella Plot
                        nameTablePlot = app.signalTablePlot{j, app.NAME_COLUMN_NUMBER};
                        nameFile = app.signalTablePlot{j, 3};
                        if strcmp(nameTableAll, nameTablePlot) && strcmp(nameFile, app.nameFile1)
                            signalPlot = get(app.UITablePlot, 'Data');
                            app.signalTablePlot{j, app.PLOT_COLUMN_NUMBER} = signalPlot{j, app.PLOT_COLUMN_NUMBER};
                            app.signalTablePlot{j, 7} = signalPlot{j, 7};
                            app.signalTablePlot{j, 8} = signalPlot{j, 8};
                            app.signalTablePlot{j, 9} = signalPlot{j, 9};
                            control = true;
                        end
                    end
                    if ~control %Se ha trovato il segnale nella tabella control è diventato true e quindi non entra in questo if, se invece è un segnale nuovo lo aggiungo alla tabella
                        newRow = {app.signalTableAllFile1{i, app.NAME_COLUMN_NUMBER}, app.signalTableAllFile1{i, app.PLOT_COLUMN_NUMBER}, char(app.nameFile1), app.signalTableAllFile1{i, app.MIN_COLUMN_NUMBER}, app.signalTableAllFile1{i, app.MAX_COLUMN_NUMBER}, app.signalTableAllFile1{i, app.UNIT_OF_MEASURE_NUMBER}, 1, 0, 0};
                        app.signalTablePlot = [app.signalTablePlot; newRow];   % La nuova riga viene aggiunta all'array dei segnali stampati nella tabella
                    end
                else % Se invece non voglio plottare il segnale oppure lo voglio togliere dalla tabella Plot
                    sizePlot = size(app.signalTablePlot);
                    for j=1:sizePlot(1)
                         nameTablePlot = app.signalTablePlot{j, app.NAME_COLUMN_NUMBER};
                         nameFile = app.signalTablePlot{j, 3};
                        if strcmp(nameTableAll, nameTablePlot) && strcmp(nameFile, app.nameFile1)
                            app.signalTablePlot(j,:) = [];  % Elimino il segnale dalla tabella Plot
                            break
                        end
                    end
                end
            end
            s = size(app.signalTableAllFile2);
            for i=1:s(1)
                nameTableAll = app.signalTableAllFile2{i, app.NAME_COLUMN_NUMBER};
                if app.signalTableAllFile2{i, app.PLOT_COLUMN_NUMBER} == true
                    sizePlot = size(app.signalTablePlot);
                    control = false;
                    for j=1:sizePlot(1)
                         nameTablePlot = app.signalTablePlot{j, app.NAME_COLUMN_NUMBER};
                         nameFile = app.signalTablePlot{j, 3};
                        if strcmp(nameTableAll, nameTablePlot) && strcmp(nameFile, app.nameFile2)
                            signalPlot = get(app.UITablePlot, 'Data');
                            app.signalTablePlot{j, app.PLOT_COLUMN_NUMBER} = signalPlot{j, app.PLOT_COLUMN_NUMBER};
                            app.signalTablePlot{j, 7} = signalPlot{j, 7};
                            app.signalTablePlot{j, 8} = signalPlot{j, 8};
                            app.signalTablePlot{j, 9} = signalPlot{j, 9};
                            control = true;
                        end
                    end
                    if ~control
                        newRow = {app.signalTableAllFile2{i, app.NAME_COLUMN_NUMBER}, app.signalTableAllFile2{i, app.PLOT_COLUMN_NUMBER}, char(app.nameFile2), app.signalTableAllFile2{i, app.MIN_COLUMN_NUMBER}, app.signalTableAllFile2{i, app.MAX_COLUMN_NUMBER}, app.signalTableAllFile2{i, app.UNIT_OF_MEASURE_NUMBER}, 1, 0, 0};
                        app.signalTablePlot = [app.signalTablePlot; newRow];   % La nuova riga viene aggiunta all'array dei segnali stampati nella tabella
                    end
                else
                    sizePlot = size(app.signalTablePlot);
                    for j=1:sizePlot(1)
                         nameTablePlot = app.signalTablePlot{j, app.NAME_COLUMN_NUMBER};
                         nameFile = app.signalTablePlot{j, 3};
                        if strcmp(nameTableAll, nameTablePlot) && strcmp(nameFile, app.nameFile2)
                            app.signalTablePlot(j,:) = [];
                            break
                        end
                    end
                end
            end
            app.UITablePlot.Data = app.signalTablePlot;
            plotUIAxes(app);
        end

        function changeDataTableLap(app) % Aggiungere controllo per rispuntare i segnali già stampati
            index = app.UITablePlot.Selection;
            signalPlot = get(app.UITablePlot, 'Data');
            gain = signalPlot{index, 7};
            offsetY = signalPlot{index, 9};
            app.signalTableLap = [];
            s = size(app.splitSignalLap(index).Laps);
            for i=1:s(2)
                visible = false;
                d = size(app.signalPlotLap);
                for j=1:d(2)
                    if strcmp(app.nameSelected, app.signalPlotLap(j).Name) && strcmp(app.fileSelected, app.signalPlotLap(j).File)
                        n = size(app.signalPlotLap(j).Lap);
                        for k=1:n(2)
                            if i == app.signalPlotLap(j).Lap(k) && get(app.signalPlotLap(j).Plot(k), 'Visible')
                                visible = true;
                            end
                        end
                    end
                end
                newRow = {i, visible, app.splitSignalLap(index).Laps(i).lapTime, app.splitSignalLap(index).Laps(i).min, app.splitSignalLap(index).Laps(i).max, gain, 0, offsetY}; 
                app.signalTableLap = [app.signalTableLap; newRow];
            end
            app.UITableLap.Data = app.signalTableLap;
        end

        function result = getSignalTable(app, name)
            if strcmp(name, app.nameFile1)
                result = app.signalTableAllFile1;
                return 
            elseif strcmp(name, app.nameFile2)
                result = app.signalTableAllFile2;
                return 
            end
        end

        function result = getSignalFile(app, name)
            if strcmp(name, app.nameFile1)
                result = app.signalFile1;
                return 
            elseif strcmp(name, app.nameFile2)
                result = app.signalFile2;
                return 
            end
        end

        function result = getNameFile(app, file)
            if file == 1
                result = app.nameFile1;
                return
            elseif file == 2
                result = app.nameFile2;
                return
            end
        end

        function fieldSearchValueChanged(app, name)
            if app.File1Button.Value
                tableValue = getSignalTable(app, app.nameFile1);
                changeDataTableAll(app, name, tableValue);
            elseif app.File2Button.Value
                tableValue = getSignalTable(app, app.nameFile2);
                changeDataTableAll(app, name, tableValue);
            end
            
        end

        function [signal] = getSignalFromName(app, name, file)
            signal = NaN; 
            if file == 1
                signalFile = getSignalFile(app, app.nameFile1);
            elseif file == 2
                signalFile = getSignalFile(app, app.nameFile2);
            end
            s = size(signalFile);
            for i=1:1:s(2)
                if(isequal(name, signalFile(i).Channelname))
                    signal = signalFile(i);
                end
            end
        end

    function removePlotSignalFromName(app, name, file)
            index = 1;
            if file == 1
                s = size(app.signalPlotFile1.Signal);
                if ~(s(2) == 0)
                    for i=1:s(2)
                        if(strcmp(name, app.signalPlotFile1.ChannelName(i)))
                            index = i;
                            break
                        end
                    end
                    delete(app.signalPlotFile1.Signal(index)); 
                    app.signalPlotFile1.ChannelName(index) = [];
                    app.signalPlotFile1.Signal(index) = [];
                end
            elseif file == 2
                s = size(app.signalPlotFile2.Signal);
                if ~(size(app.signalPlotFile2) == 0)
                    for i=1:1:s(2)
                        if(isequal(name, app.signalPlotFile2.ChannelName(i)))
                            index = i;
                            break
                        end
                    end
                    delete(app.signalPlotFile2.Signal(index)); 
                    app.signalPlotFile2.ChannelName(index) = [];
                    app.signalPlotFile2.Signal(index) = [];
                end
            end
        end
        
        function indexRow = getSignalRowFromName(app, name)
            s = size(app.signalTableAllFile1);
            for i=1:s(1)
                if strcmp(name, app.signalTableAllFile1{i, app.NAME_COLUMN_NUMBER})
                    indexRow = i;
                    return
                end
            end
        end

        function index = getIndexSignalPlotFromName(app, name, file)
            if file == 1
                s = size(app.signalPlotFile1.Signal);
                for i=1:s(2)
                    if strcmp(name, app.signalPlotFile1.ChannelName(i))
                        index = i;
                        return
                    end
                end
            elseif file == 2
                 s = size(app.signalPlotFile2.Signal);
                for i=1:s(2)
                    if strcmp(name, app.signalPlotFile2.ChannelName(i))
                        index = i;
                        return
                    end
                end
            end
        end

        function index = getIndexSignalFromName(~,name, signal)
            s = size(signal);
            for i=1:s(2)
                if strcmp(name, signal(i).Channelname)
                    index = i;
                    return
                end
            end
        end

        function plotUIAxes(app)
            hold(app.UIAxes,'on');               % It is neccesary to plot multiple data
            s = size(app.signalPlotFile1.Signal);
            while s > 0
                name = app.signalPlotFile1.ChannelName(1);
                removePlotSignalFromName(app, name, 1);
                s = size(app.signalPlotFile1.Signal);
            end

            s = size(app.signalPlotFile2.Signal);
            while s > 0
                name = app.signalPlotFile2.ChannelName(1);
                removePlotSignalFromName(app, name, 2);
                s = size(app.signalPlotFile2.Signal);
            end

            s = size(app.signalTablePlot);
            for i=1:s(1)
                fileName = app.signalTablePlot{i, 3};
                name = app.signalTablePlot{i, app.NAME_COLUMN_NUMBER};
                if strcmp(fileName, app.nameFile1)
                    if app.signalTablePlot{i, app.PLOT_COLUMN_NUMBER} == true
                        plotSignal = getSignalFromName(app, name, 1);
                        gain = app.signalTablePlot{i, 7};
                        offsetX = app.signalTablePlot{i, 8};
                        offsetY = app.signalTablePlot{i, 9};
                        n = plot(app.UIAxes, plotSignal.time + offsetX, plotSignal.signals.values * gain + offsetY, 'DisplayName', plotSignal.Channelname  + " [" + fileName + "]");    %Plot signal
                        app.signalPlotFile1.Signal = [app.signalPlotFile1.Signal, n];        % Upload plotVar array
                        app.signalPlotFile1.ChannelName = [app.signalPlotFile1.ChannelName, string(name)];
                        set(n, 'Visible', 'on');
                    else
                        plotSignal = getSignalFromName(app, name, 1);
                        gain = app.signalTablePlot{i, 7};
                        offsetX = app.signalTablePlot{i, 8};
                        offsetY = app.signalTablePlot{i, 9};
                        n = plot(app.UIAxes, plotSignal.time + offsetX, plotSignal.signals.values * gain + offsetY, 'DisplayName', plotSignal.Channelname  + " [" + fileName + "]");    %Plot signal
                        app.signalPlotFile1.Signal = [app.signalPlotFile1.Signal, n];        % Upload plotVar array
                        app.signalPlotFile1.ChannelName = [app.signalPlotFile1.ChannelName, string(name)];
                        set(n, 'Visible', 'off');
                    end
                elseif strcmp(fileName, app.nameFile2)
                    if app.signalTablePlot{i, app.PLOT_COLUMN_NUMBER} == true
                        plotSignal = getSignalFromName(app, name, 2);
                        gain = app.signalTablePlot{i, 7};
                        offsetX = app.signalTablePlot{i, 8};
                        offsetY = app.signalTablePlot{i, 9};
                        n = plot(app.UIAxes, plotSignal.time + offsetX, plotSignal.signals.values * gain + offsetY, 'DisplayName', plotSignal.Channelname  + " [" + fileName + "]");    %Plot signal
                        app.signalPlotFile2.Signal = [app.signalPlotFile2.Signal, n];        % Upload plotVar array
                        app.signalPlotFile2.ChannelName = [app.signalPlotFile2.ChannelName, string(name)];
                        set(n, 'Visible', 'on');
                    else
                        plotSignal = getSignalFromName(app, name, 2);
                        gain = app.signalTablePlot{i, 7};
                        offsetX = app.signalTablePlot{i, 8};
                        offsetY = app.signalTablePlot{i, 9};
                        n = plot(app.UIAxes, plotSignal.time + offsetX, plotSignal.signals.values * gain + offsetY, 'DisplayName', plotSignal.Channelname  + " [" + fileName + "]");    %Plot signal
                        app.signalPlotFile2.Signal = [app.signalPlotFile2.Signal, n];        % Upload plotVar array
                        app.signalPlotFile2.ChannelName = [app.signalPlotFile2.ChannelName, string(name)];
                        set(n, 'Visible', 'off');
                    end
                end
            end
        end

        function plotLap(app, index)
            structPlotLap = struct('Name', "", 'File', "", 'Lap', [], 'Plot', []);
            s = size(app.splitSignalLap);
            for i=1:s(2)
                if strcmp(app.nameSelected, app.splitSignalLap(i).Name) && strcmp(app.fileSelected, app.splitSignalLap(i).File)
                    d = size(app.signalPlotLap);
                    control = false;
                    for j=1:d(2)
                        if strcmp(app.nameSelected, app.signalPlotLap(j).Name) && strcmp(app.fileSelected, app.signalPlotLap(j).File)
                            control = true;
                            isPlot = false;
                            d = size(app.signalPlotLap(j).Lap);
                            for k=1:d(2)
                                if index == app.signalPlotLap(j).Lap(k)
                                    set(app.signalPlotLap(j).Plot(k), 'Visible', 'on');
                                    isPlot = true;
                                    break
                                end
                            end
                            if ~isPlot
                                n = plot(app.UIAxes, app.splitSignalLap(i).Laps(index).X, app.splitSignalLap(i).Laps(index).Y, 'DisplayName', app.nameSelected  + " Lap: " + index + " [" + app.fileSelected + "]");
                                app.signalPlotLap(j).Lap = [app.signalPlotLap(j).Lap, index];
                                app.signalPlotLap(j).Plot = [app.signalPlotLap(j).Plot, n];
                                break
                            end
                        end
                    end
                    if ~control
                        n = plot(app.UIAxes, app.splitSignalLap(i).Laps(index).X, app.splitSignalLap(i).Laps(index).Y, 'DisplayName', app.nameSelected  + " Lap: " + index + " [" + app.fileSelected + "]");
                        structPlotLap.Name = app.nameSelected;
                        structPlotLap.File = app.fileSelected;
                        structPlotLap.Lap = [structPlotLap.Lap, index];
                        structPlotLap.Plot = [structPlotLap.Plot, n];
                        app.signalPlotLap = [app.signalPlotLap, structPlotLap];
                    end
                end
            end
        %dividere i segnali appena vengono stampati
        end
        
        function result = getSelectedSignalName(app)
            s = size(app.signalPlotFile1.Signal);
            for i=1:s(2)
                if ~isempty(get(app.signalPlotFile1.Signal, 'Children'))
                    result = app.nameFile1;
                    return
                end
            end
            s = size(app.signalPlotFile2.Signal);
            for i=1:s(2)
                if ~isempty(get(app.signalPlotFile2.Signal, 'Children'))
                    result = app.nameFile2;
                    return
                end
            end
            result = "";
            return
        end
        
        function setValue(app, name, signal, x)
            if strcmp(name, "Vcu9_62_VehicleSpeed_02")
                index = getIndexSignalFromName(app, name, signal);
                app.y = double(interp1(signal(index).time, signal(index).signals.values, x));
                text = "Speed: " + string(app.y*3.6) + " km/h";
                set(app.LabelSpeed, 'Text', text);
            elseif strcmp(name, "Vcu2_55_rpm_02")
                index = getIndexSignalFromName(app, name, signal);
                app.y = double(interp1(signal(index).time, signal(index).signals.values, x));
                set(app.LabelRPM, 'Text', "RPM: " + string(app.y) + " giri/minuto");
            elseif strcmp(name, "BmsInfoMessa_05_SOC_02")
                index = getIndexSignalFromName(app, name, signal);
                app.y = double(interp1(signal(index).time, signal(index).signals.values, x));
                set(app.LabelSOC, 'Text', "SOC: " + string(app.y) + " %");
             elseif strcmp(name, "Vcu2_55_Steering_03")
                 index = getIndexSignalFromName(app, name, signal);
                 app.y = double(interp1(signal(index).time, signal(index).signals.values, x));
                 if ~isnan(app.y)
                    set(app.LabelSteer, 'Text', string(app.y) + " °");
                    i = imread('pngegg.png');
                    j = imrotate(i, app.y);
                    j(j==0) = 240;
                    app.Steer.ImageSource = j;
                 end
            elseif strcmp(name, "Vcu4_57_APPS1_00")
                index = getIndexSignalFromName(app, name, signal);
                app.y = int8(interp1(signal(index).time, signal(index).signals.values, x));
                set(app.rectAccelerator, 'Position', [2, 1, 1, app.y]);
                set(app.LabelAccelerator, 'Text', string(app.y) + " %");
            elseif strcmp(name, "Vcu2_55_pressure2_01")
                index = getIndexSignalFromName(app, name, signal);
                value = double(interp1(signal(index).time, signal(index).signals.values, x));
                app.y = int8((100*value)/35);
                set(app.rectBrake, 'Position', [0, 1, 1, app.y])
                set(app.LabelBrake, 'Text', string(app.y) + " %");
            end
        end

        function setValueButtons(app, name, signal)
            if strcmp(name, "Vcu4_57_APPS1_00")
                index = getIndexSignalFromName(app, name, signal);
                app.yButtons = int8(interp1(signal(index).time, signal(index).signals.values, app.xButtons));
                set(app.rectAcceleratorButtons, 'Position', [2, 1, 1, app.yButtons]);
                set(app.LabelAcceleratorButtons, 'Text', string(app.yButtons) + " %");
            elseif strcmp(name, "Vcu2_55_pressure2_01")
                index = getIndexSignalFromName(app, name, signal);
                value = double(interp1(signal(index).time, signal(index).signals.values, app.xButtons));
                app.yButtons = int8((100*value)/35);
                set(app.rectBrakeButtons, 'Position', [0, 1, 1, app.yButtons])
                set(app.LabelBrakeButtons, 'Text', string(app.yButtons) + " %");
            end
        end
        
        function setYValue(app)
            dataTablePlot = get(app.UITablePlot, 'Data');
            selection = app.UITablePlot.Selection;
            if dataTablePlot{selection, app.PLOT_COLUMN_NUMBER}
                if strcmp(app.fileSelected, app.nameFile1)
                    s = size(app.signalFile1);
                    for i=1:s(2)
                        if strcmp(app.nameSelected, app.signalFile1(i).Channelname)
                            app.y = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.x));
                            if app.x <= 0
                                while isnan(app.y)
                                    app.x = app.x + 0.01;
                                    app.y = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.x));
                                end
                            end
                            if isnan(app.y)
                                app.x = 0;
                                app.y = 0;
                            end
                            set(app.YEditField, 'Value', app.y);
                            set(app.XEditField, 'Value', app.x);
                        end
                    end
                    currentXLinePlot(app);
                elseif strcmp(app.fileSelected, app.nameFile2)
                    s = size(app.signalFile2);
                    for i=1:s(2)
                        if strcmp(app.nameSelected, app.signalFile2(i).Channelname)
                            app.y = double(interp1(app.signalFile2(i).time, app.signalFile2(i).signals.values, app.x));
                            if app.x <= 0
                                while isnan(app.y)
                                    app.x = app.x + 0.01;
                                    app.y = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.x));
                                end
                            end
                            if isnan(app.y)
                                app.x = 0;
                                app.y = 0;
                            end
                            set(app.YEditField, 'Value', app.y);
                            set(app.XEditField, 'Value', app.x);
                        end
                    end
                    currentXLinePlot(app);
                end
                if strcmp(app.fileSelected, app.nameFile1)
                    setValue(app, "Vcu9_62_VehicleSpeed_02", app.signalFile1, app.x);
                    setValue(app, "Vcu2_55_rpm_02", app.signalFile1, app.x);
                    setValue(app, "BmsInfoMessa_05_SOC_02", app.signalFile1, app.x);
                    setValue(app, "Vcu4_57_APPS1_00", app.signalFile1, app.x);
                    setValue(app, "Vcu2_55_pressure2_01", app.signalFile1, app.x);
                    setValue(app, "Vcu2_55_Steering_03", app.signalFile1, app.x);
                elseif strcmp(app.fileSelected, app.nameFile2)
                    setValue(app, "Vcu9_62_VehicleSpeed_02", app.signalFile2, app.x);
                    setValue(app, "Vcu2_55_rpm_02", app.signalFile2, app.x);
                    setValue(app, "BmsInfoMessa_05_SOC_02", app.signalFile2, app.x);
                    setValue(app, "Vcu4_57_APPS1_00", app.signalFile2, app.x);
                    setValue(app, "Vcu2_55_pressure2_01", app.signalFile2, app.x);
                    setValue(app, "Vcu2_55_Steering_03", app.signalFile2, app.x);
                end
            else
                selectionLap = app.UITableLap.Selection;
                setYLapValue(app, selectionLap);
            end
        end
        
        function setYLapValue(app, selection)
            s = size(app.splitSignalLap);
            startTime = 0;
            for i=1:s(2)
                if strcmp(app.nameSelected, app.splitSignalLap(i).Name) && strcmp(app.fileSelected, app.splitSignalLap(i).File)
                    signalLap = app.splitSignalLap(i).Laps(selection);
                    app.y = double(interp1(signalLap.X, signalLap.Y, app.x));
                    startTime = signalLap.startTime;
                    if isnan(app.y)
                        app.x = 0;
                        app.y = double(interp1(signalLap.X, signalLap.Y, app.x));
                    end
                    set(app.YEditField, 'Value', app.y);
                    set(app.XEditField, 'Value', app.x);
                end
            end
            currentXLinePlot(app);
            if strcmp(app.fileSelected, app.nameFile1)
                setValue(app, "Vcu9_62_VehicleSpeed_02", app.signalFile1, app.x + startTime);
                setValue(app, "Vcu2_55_rpm_02", app.signalFile1, app.x + startTime);
                setValue(app, "BmsInfoMessa_05_SOC_02", app.signalFile1, app.x + startTime);
                setValue(app, "Vcu4_57_APPS1_00", app.signalFile1, app.x + startTime);
                setValue(app, "Vcu2_55_pressure2_01", app.signalFile1, app.x + startTime);
                setValue(app, "Vcu2_55_Steering_03", app.signalFile1, app.x + startTime);
            elseif strcmp(app.fileSelected, app.nameFile2)
                setValue(app, "Vcu9_62_VehicleSpeed_02", app.signalFile2, app.x + startTime);
                setValue(app, "Vcu2_55_rpm_02", app.signalFile2, app.x + startTime);
                setValue(app, "BmsInfoMessa_05_SOC_02", app.signalFile2, app.x + startTime);
                setValue(app, "Vcu4_57_APPS1_00", app.signalFile2, app.x + startTime);
                setValue(app, "Vcu2_55_pressure2_01", app.signalFile2, app.x + startTime);
                setValue(app, "Vcu2_55_Steering_03", app.signalFile2, app.x + startTime);
            end
        end

        function setYValueButtons(app)
            if strcmp(app.fileSelectedButtons, app.nameFile1) % Se il segnale selezionato è del file 1
                s = size(app.signalFile1);
                for i=1:s(2) % iteriamo l'array dei segnali del file 1
                    if strcmp(app.nameSelectedButtons, app.signalFile1(i).Channelname) % Se troviamo quello con il nome della selezione
                        app.yButtons = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.xButtons)); % Calcoliamo il punto corrispondente alla X inserita grazie all'interpolazione
                        while isnan(app.yButtons) % se la Y calcolata è uguale a NaN vado a cercare il primo valore non NaN
                            app.xButtons = app.xButtons + 0.01;
                            app.yButtons = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.xButtons));
                        end % Esco dal while quando trovo il primo valore non NaN
                        set(app.YEditFieldButtons, 'Value', app.yButtons); % Setto il valore della Y trovato nel field
                    end
                end
                currentXLinePlotButtons(app); % Plotto la linea verticale in corrispondenza della X
            elseif strcmp(app.fileSelectedButtons, app.nameFile2) % Rifaccio lo stesso procedimento con l'altro file
                s = size(app.signalFile2);
                for i=1:s(2)
                    if strcmp(app.nameSelectedButtons, app.signalFile2(i).Channelname)
                        app.yButtons = double(interp1(app.signalFile2(i).time, app.signalFile2(i).signals.values, app.xButtons));
                        while isnan(app.yButtons)
                            app.xButtons = app.xButtons + 0.01;
                            app.yButtons = double(interp1(app.signalFile1(i).time, app.signalFile1(i).signals.values, app.xButtons));
                        end
                        set(app.YEditFieldButtons, 'Value', app.yButtons);
                    end
                end
                currentXLinePlotButtons(app);
            end
            if strcmp(app.fileSelectedButtons, app.nameFile1)
                setValueButtons(app, "Vcu4_57_APPS1_00", app.signalFile1);
                setValueButtons(app, "Vcu2_55_pressure2_01", app.signalFile1);
            elseif strcmp(app.fileSelectedButtons, app.nameFile2)
                setValueButtons(app, "Vcu4_57_APPS1_00", app.signalFile2);
                setValueButtons(app, "Vcu2_55_pressure2_01", app.signalFile2);
            end
        end

        function currentXLinePlot(app)
            set(app.currentXLine, 'Value', app.x);
        end

        function currentXLinePlotButtons(app)
            set(app.currentXLineButtons, 'Value', app.xButtons);
        end
        
        function splitLap(app, name, file)
            signal = getSignalFromName(app, name, file);
            s = size(app.detectedTime);
            sT = size(signal.time);
            totalLap = struct('Name', "", 'File', "", 'Laps', []);
            for i=1:s(2)-1
                lap = struct('X', [], 'Y', [], 'min', 1000000, 'max', -1000000, 'lapTime', 0, 'startTime', 0);
                lap.lapTime = app.detectedTime(i+1) - app.detectedTime(i);
                for j=1:sT(1)
                    if signal.time(j) >= app.detectedTime(i) && signal.time(j) <= app.detectedTime(i+1)
                        lap.X = [lap.X; signal.time(j)];
                        lap.Y = [lap.Y; signal.signals.values(j)];
                        if signal.signals.values(j) < lap.min
                            lap.min = signal.signals.values(j);
                        elseif signal.signals.values(j) > lap.max
                            lap.max = signal.signals.values(j);
                        end
                    end
                end
                lap.startTime = lap.X(1);
                lap.X = lap.X - lap.X(1);
                totalLap.Laps = [totalLap.Laps, lap];
            end
            totalLap.Name = name;
            totalLap.File = getNameFile(app, file);
            app.splitSignalLap = [app.splitSignalLap, totalLap];
        end

        function plotGuideSignal(app)
            if ~strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.guideSignal(1);
                app.guideSignal(2);
            elseif ~strcmp(app.nameFile1, "-") && strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', false);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.guideSignal(1);
            elseif strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', false);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.guideSignal(2);
            else
            end
        end

        function guideSignal(app, file) % se non funziona aggiungere char al nome del file
            signal = getSignalFromName(app, "Vcu9_62_VehicleSpeed_02", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu2_55_Steering_03", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values + 25.3, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 25.3};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "InvRx_Torque_28_D2_Torque_Fe_01", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "InvRx_Comman_12_Torque_Comma_06", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];   

            app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

        function plotTurnAndShockAbs(app)
            if ~strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.turnAndShockAbs(1);
                app.turnAndShockAbs(2);
            elseif ~strcmp(app.nameFile1, "-") && strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', false);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.turnAndShockAbs(1);
            elseif strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', false);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.turnAndShockAbs(2);
            else
            end
        end

        function turnAndShockAbs(app, file)
            signal = getSignalFromName(app, "Vcu1_54_extensometer_ant_dx_00", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values - 53.2, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -53.2};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu1_54_extensometer_ant_sx_01", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values - 51.1, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -51.1};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu1_54_extensometer_pos_dx_02", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -53.2};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu1_54_extensometer_pos_sx_03", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -53.2};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu2_55_Steering_03", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values * 0.1 + 2.6, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 0.1, 0, +2.6};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];
            app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

        function plotBrake(app)
            if ~strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.brake(1);
                app.brake(2);
            elseif ~strcmp(app.nameFile1, "-") && strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', false);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.brake(1);
            elseif strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', false);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.brake(2);
            else
            end
        end

        function brake(app, file)
            signal = getSignalFromName(app, "Vcu2_55_pressure1_00", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values - 1.3, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -1.3};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu2_55_pressure2_01", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values - 1.1, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, -1.1};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            signal = getSignalFromName(app, "Vcu9_62_VehicleSpeed_02", file);
            p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
            newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
            app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
            app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
            app.signalTableButtons = [app.signalTableButtons; newRow];

            app.UITableButtonsPlot.Data = app.signalTableButtons;
            
        end

        function plotBattery(app)
            if ~strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.battery(1);
                app.battery(2);
            elseif ~strcmp(app.nameFile1, "-") && strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', false);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.battery(1);
            elseif strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', false);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.battery(2);
            else
            end
        end

        function battery(app, file)

                signal = getSignalFromName(app, "Vcu4_57_APPS1_00", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu2_55_pressure2_01", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu9_62_VehicleSpeed_02", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu2_55_Steering_03", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "InvRx_Torque_28_D2_Torque_Fe_01", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "InvRx_Comman_12_Torque_Comma_06", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];

                app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

        function plotEngine(app)
            if ~strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.engine(1);
                app.engine(2);
            elseif ~strcmp(app.nameFile1, "-") && strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', true);
                set(app.CheckBoxFile2, 'Value', false);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.engine(1);
            elseif strcmp(app.nameFile1, "-") && ~strcmp(app.nameFile2, "-")
                set(app.CheckBoxFile1, 'Value', false);
                set(app.CheckBoxFile2, 'Value', true);
                s = size(app.signalPlotButtons.Signal);
                if s(2) > 0
                    for i = s(2):-1:1
                        delete(app.signalPlotButtons.Signal(i));
                    end
                    app.signalPlotButtons.Signal = [];
                    app.signalPlotButtons.ChannelName = [];
                    app.signalTableButtons = [];
                end
                app.engine(2);
            else
            end
        end

        function engine(app, file)
                %soc
                signal = getSignalFromName(app, "Vcu4_57_APPS1_00", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu2_55_pressure2_01", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu9_62_VehicleSpeed_02", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "Vcu2_55_Steering_03", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "InvRx_Torque_28_D2_Torque_Fe_01", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
    
                signal = getSignalFromName(app, "InvRx_Comman_12_Torque_Comma_06", file);
                p = plot(app.UIAxesButtons, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + " [" + getNameFile(app, file) + "]");
                newRow = {signal.Channelname, true, char(getNameFile(app, file)), signal.min, signal.max, signal.unit, 1, 0, 0};
                app.signalPlotButtons.ChannelName = [app.signalPlotButtons.ChannelName, string(signal.Channelname)];
                app.signalPlotButtons.Signal = [app.signalPlotButtons.Signal, p];
                app.signalTableButtons = [app.signalTableButtons; newRow];
                
                app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

%         function drawTrack(app)  guarda un singolo punto e non l'arco
%             %signalx = getSignalFromName(app, "Longitudine", 1);
%             %signaly = getSignalFromName(app, "Latitudine", 1);
%             latitudine  = [20, 21, 22, 23, 24, 25, 26, 27, 28, 29, 30, 30, 30, 30, 30, 30, 30, 30, 30, 30, 30, 29, 28, 27, 26, 25, 24, 23, 22, 21, 20, 20, 20, 20, 20, 20, 20, 20, 20, 20];
%             longitudine = [-100, -110, -102, -130, -104, -150, -104, -130, -120, -101, -100, -110, -120, -130, -140, -105, -160, -170, -108, -190, -200, -200, -200, -200, -200, -200, -200, -200, -200, -200, -200, -190, -180, -170, -160, -150, -140, -103, -120, -110];
%             s = size(longitudine);
%             for i=1:s(2)
%                 disp(longitudine(i))
%                 plot(app.UITrack, longitudine(i), latitudine(i), 'ko');
%             end
%         end
        function drawTrack(app)
            %signalx = getSignalFromName(app, "Longitudine", 1);
            %signaly = getSignalFromName(app, "Latitudine", 1);
             latitudine  = [20, 21, 22, 23, 24, 25, 26, 27, 28, 29, 30, 30, 30, 30, 30, 30, 30, 30, 30, 30, 30, 29, 28, 27, 26, 25, 24, 23, 22, 21, 20, 20, 20, 20, 20, 20, 20, 20, 20, 20];
             longitudine = [-100, -110, -102, -130, -104, -150, -104, -130, -120, -101, -100, -110, -120, -130, -140, -105, -160, -170, -108, -190, -200, -200, -200, -200, -200, -200, -200, -200, -200, -200, -200, -190, -180, -170, -160, -150, -140, -103, -120, -110];
%             s = size(longitudine);
%             xx=0;
%             yy=0;
%             plot(app.UITrack, xx, yy, 'ko');
%             for i=1:s(2)-1
%                 dx = longitudine(i+1) - longitudine(i);
%                 dx = dx*2*3.14*6371/360;
%                 dy = latitudine(i+1) - latitudine(i);
%                 dy = dy*2*3.14*6371/360;
%                 xx = xx + dx;
%                 yy = yy + dy;
%                 plot(app.UITrack, xx, yy, 'ko');
%             end
            plot(app.UITrack, latitudine, longitudine);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fullfile("pngegg.png");
            legend(app.UIAxes, 'on',  'Interpreter', 'none');  % Set legend On
            legend(app.UIAxesButtons, 'on',  'Interpreter', 'none');
            app.signalPlotFile1 = struct('Signal', [], 'ChannelName', []);   % Init struct
            app.signalPlotFile2 = struct('Signal', [], 'ChannelName', []);   % Init struct
            app.signalPlotButtons = struct('Signal', [], 'ChannelName', []);
            rectangle(app.UIPedal, 'Position', [0, 1, 1, 100]);
            rectangle(app.UIPedal, 'Position', [2, 1, 1, 100]);
            rectangle(app.UIPedalButtons, 'Position', [0, 1, 1, 100]);
            rectangle(app.UIPedalButtons, 'Position', [2, 1, 1, 100]);
            app.rectBrake = rectangle(app.UIPedal, 'Position', [0, 1, 1, 0], 'FaceColor', 'red', 'EdgeColor', 'red');
            app.rectAccelerator = rectangle(app.UIPedal, 'Position', [2, 1, 1, 0], 'FaceColor', 'green', 'EdgeColor', 'green');
            app.rectBrakeButtons = rectangle(app.UIPedalButtons, 'Position', [0, 1, 1, 0], 'FaceColor', 'red', 'EdgeColor', 'red');
            app.rectAcceleratorButtons = rectangle(app.UIPedalButtons, 'Position', [2, 1, 1, 0], 'FaceColor', 'green', 'EdgeColor', 'green');
            app.currentXLine = xline(app.UIAxes, 0);
            app.currentXLineButtons = xline(app.UIAxesButtons, 0);
            circle(app, 0, 0, 1);
            circle(app, 0, 0, 2);
            circle(app, 0, 0, 3);
            circle(app, 0, 0, 4);
            circle(app, 0, 0, 5);
            set(get( get(app.currentXLine, 'Annotation'), 'LegendInformation'), 'IconDisplayStyle', 'off');
            set(get( get(app.currentXLineButtons, 'Annotation'), 'LegendInformation'), 'IconDisplayStyle', 'off');
            hold(app.UIAxesButtons,'on');
        end

        % Button pushed function: Settings
        function SettingsButtonPushed(app, event)
            app.Panel.Visible = 'on';
            if app.nameFile1 ~= "-" || app.nameFile2 ~= "-"
                if app.File1Button.Value
                    changeDataTableAll(app, "", getSignalTable(app, app.nameFile1));
                elseif app.File2Button.Value
                    changeDataTableAll(app, "", getSignalTable(app, app.nameFile2));
                end
            end
        end

        % Button down function: Panel
        function PanelButtonDown(app, event)
            app.Panel.Visible = 'off';
            app.splitSignalLap = [];
            %Faccio la suddivisione in giri quando schiaccia il pannello
            %per tornare nella pagina principale perché è un operazione che
            %richiede risorse in questo modo non si nota il delay che ci
            %mette a suddividere in giri
            s = size(app.signalTablePlot);
            for i=1:s(1)
                if strcmp(app.signalTablePlot{i, 3}, app.nameFile1)
                    file = 1;
                elseif strcmp(app.signalTablePlot{i, 3}, app.nameFile2)
                    file = 2;
                end
                splitLap(app, app.signalTablePlot{i, app.NAME_COLUMN_NUMBER}, file);
            end
        end

        % Button pushed function: LoadFile1
        function LoadFile1ButtonPushed(app, event)
            ClearGraph(app);
            app.signalPlotFile1.Signal = [];
            app.signalPlotFile1.ChannelName = [];
            app.signalTableAllFile1 = [];
            app.signalFile1 = [];
            [file,path] = uigetfile('*.mat');              % Carica il file .m selezionato dall'utente
            app.nameFile1 = string(file);
            if ~isequal(file,0)
                v = importdata(string(path)+string(file)); % Importa il file
                v = rmfield(v,'header');                   % Questo dato è nel file ma non è un segnale quindi va eliminato
                app.signalFile1 = struct2array(v);                % Converte i segnali nell'array signalFile1
            end
            s = size(app.signalFile1);
            for i=1:1:s(2)
                 newRow = {app.signalFile1(i).Channelname, false, app.signalFile1(i).min, app.signalFile1(i).max, app.signalFile1(i).unit};  %Inizializzazione nuova riga
                 app.signalTableAllFile1 = [app.signalTableAllFile1; newRow];   % La nuova riga viene aggiunta all'array dei segnali stampati nella tabella
            end
            set(app.File1Button, 'Text', app.nameFile1);
            set(app.File1Button, 'Value', true);
            changeDataTableAll(app, "", getSignalTable(app, app.nameFile1));
            drawTrack(app);
        end

        % Button pushed function: LoadFile2
        function LoadFile2ButtonPushed(app, event)
            ClearGraph(app);
            app.signalPlotFile2.Signal = [];
            app.signalPlotFile2.ChannelName = [];
            app.signalTableAllFile2 = [];
            app.signalFile2 = [];
            [file,path] = uigetfile('*.mat');              % Carica il file .m selezionato dall'utente
            app.nameFile2 = string(file);
            if ~isequal(file,0)
                v = importdata(string(path)+string(file)); % Importa il file
                v = rmfield(v,'header');                   % Questo dato è nel file ma non è un segnale quindi va eliminato
                app.signalFile2 = struct2array(v);                % Converte i segnali nell'array signalFile1
            end
            s = size(app.signalFile2);
            for i=1:1:s(2)
                 newRow = {app.signalFile2(i).Channelname, false, app.signalFile2(i).min, app.signalFile2(i).max, app.signalFile2(i).unit};  %Inizializzazione nuova riga
                 app.signalTableAllFile2 = [app.signalTableAllFile2; newRow];   % La nuova riga viene aggiunta all'array dei segnali stampati nella tabella
            end
            set(app.File2Button, 'Text', app.nameFile2);
            set(app.File2Button, 'Value', true);
            changeDataTableAll(app, "", getSignalTable(app, app.nameFile2));
        end

        % Value changing function: FieldSearch
        function FieldSearchValueChanging(app, event)
            changingValue = event.Value;
            if app.File1Button.Value
                tableValue = getSignalTable(app, app.nameFile1);
                changeDataTableAll(app, changingValue, tableValue);
            elseif app.File2Button.Value
                tableValue = getSignalTable(app, app.nameFile2);
                changeDataTableAll(app, changingValue, tableValue);
            end
        end

        % Cell edit callback: UITableAll
        function UITableAllCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == app.PLOT_COLUMN_NUMBER      % L'utente modifica la seconda colonna per stampare il segnale
                if newData == true                    % Se newData è true allora dovrò plottare il segnale
                    app.signalTable = get(app.UITableAll, 'Data');
                    name = app.signalTable{indices(1), app.NAME_COLUMN_NUMBER};
                    row = getSignalRowFromName(app, name);
                    if app.File1Button.Value
                        app.signalTableAllFile1{row, app.PLOT_COLUMN_NUMBER} = true;
                    elseif app.File2Button.Value
                        app.signalTableAllFile2{row, app.PLOT_COLUMN_NUMBER} = true;
                    end

                else
                    app.signalTable = get(app.UITableAll, 'Data');
                    name = app.signalTable{indices(1), app.NAME_COLUMN_NUMBER};
                    row = getSignalRowFromName(app, name);
                    if app.File1Button.Value
                        name = app.signalTableAllFile1{row, app.NAME_COLUMN_NUMBER};
                        app.signalTableAllFile1{row, app.PLOT_COLUMN_NUMBER} = false;
                        removePlotSignalFromName(app, name, 1);        % Upload plotVar array
                    elseif app.File2Button.Value
                         name = app.signalTableAllFile2{row, app.NAME_COLUMN_NUMBER};
                         app.signalTableAllFile2{row, app.PLOT_COLUMN_NUMBER} = false;
                         removePlotSignalFromName(app, name, 2);        % Upload plotVar array
                    end
                end
            end
            changeDataTablePlot(app);
        end

        % Cell edit callback: UITablePlot
        function UITablePlotCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == app.PLOT_COLUMN_NUMBER      % User modify 2nd column with checkbox
                if newData == false
                    row = getSignalRowFromName(app, app.signalTablePlot{indices(1), app.NAME_COLUMN_NUMBER});
                    if strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile1)
                        name = app.signalTableAllFile1{row, app.NAME_COLUMN_NUMBER};
                        %app.signalTableAllFile1{row, app.PLOT_COLUMN_NUMBER} = false;
                        s = size(app.signalPlotFile1.Signal);
                        for i=1:s(2)
                            if strcmp(name, app.signalPlotFile1.ChannelName(i))
                                set(app.signalPlotFile1.Signal(i), 'Visible', 'off');
                            end
                        end
                        %removePlotSignalFromName(app, name, 1);
                    elseif strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile2)
                         name = app.signalTableAllFile2{row, app.NAME_COLUMN_NUMBER};
                         %app.signalTableAllFile2{row, app.PLOT_COLUMN_NUMBER} = false;
                         s = size(app.signalPlotFile2.Signal);
                         for i=1:s(2)
                            if strcmp(name, app.signalPlotFile2.ChannelName(i))
                                set(app.signalPlotFile2.Signal(i), 'Visible', 'off');
                            end
                        end
                         %removePlotSignalFromName(app, name, 2);        % Upload plotVar array
                    end
                else
                    row = getSignalRowFromName(app, app.signalTablePlot{indices(1), app.NAME_COLUMN_NUMBER});
                    if strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile1)
                        name = app.signalTableAllFile1{row, app.NAME_COLUMN_NUMBER};
                        %app.signalTableAllFile1{row, app.PLOT_COLUMN_NUMBER} = false;
                        s = size(app.signalPlotFile1.Signal);
                        for i=1:s(2)
                            if strcmp(name, app.signalPlotFile1.ChannelName(i))
                                set(app.signalPlotFile1.Signal(i), 'Visible', 'on');
                            end
                        end
                        %removePlotSignalFromName(app, name, 1);
                    elseif strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile2)
                         name = app.signalTableAllFile2{row, app.NAME_COLUMN_NUMBER};
                         %app.signalTableAllFile2{row, app.PLOT_COLUMN_NUMBER} = false;
                         s = size(app.signalPlotFile2.Signal);
                         for i=1:s(2)
                            if strcmp(name, app.signalPlotFile2.ChannelName(i))
                                set(app.signalPlotFile2.Signal(i), 'Visible', 'on');
                            end
                        end
                         %removePlotSignalFromName(app, name, 2);        % Upload plotVar array
                    end
                end
            elseif indices(2) == 7 || indices(2) == 8 || indices(2) == 9
                app.signalTablePlot = get(app.UITablePlot, 'Data');
                name = app.signalTablePlot{indices(1), app.NAME_COLUMN_NUMBER}; % Estraggo il nome dalla tabella
                gain = app.signalTablePlot{indices(1), 7};     % Estraggo gain dalla tabella
                offsetX = app.signalTablePlot{indices(1), 8};  % Estraggo offsetX dalla tabella
                offsetY = app.signalTablePlot{indices(1), 9};  % Estraggo offsetY dalla tabella
                if strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile1)
                    signal = getSignalFromName(app, name, 1);
                    s = size(app.signalPlotFile1.ChannelName);
                    for i=1:s(2)
                        if strcmp(name, app.signalPlotFile1.ChannelName(i))
                            set(app.signalPlotFile1.Signal(i), 'XData', signal.time + offsetX, 'YData', signal.signals.values * gain + offsetY)
                        end
                    end
                elseif strcmp(app.signalTablePlot{indices(1), 3}, app.nameFile2)
                    signal = getSignalFromName(app, name, 2);
                    s = size(app.signalPlotFile2.ChannelName);
                    for i=1:s(2)
                        if strcmp(name, app.signalPlotFile2.ChannelName(i))
                            set(app.signalPlotFile2.Signal(i), 'XData', signal.time + offsetX, 'YData', signal.signals.values * gain + offsetY)
                        end
                    end
                end
            end
        end

        % Callback function: UITableAll
        function UITableAllSelectionChanged(app, event)
            try
                selection = app.UITableAll.Selection;
                if app.File1Button.Value
                    app.signalTable = get(app.UITableAll, 'Data');
                    name = app.signalTable{selection, app.NAME_COLUMN_NUMBER};
                    signal = getSignalFromName(app, name, 1);
                    plot(app.UIAxesPreview, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + "[" + signal.unit + "]");    %Plot signal
                elseif app.File2Button.Value
                    app.signalTable = get(app.UITableAll, 'Data');
                    name = app.signalTable{selection, app.NAME_COLUMN_NUMBER};
                    signal = getSignalFromName(app, name, 2);
                    plot(app.UIAxesPreview, signal.time, signal.signals.values, 'DisplayName', signal.Channelname  + "[" + signal.unit + "]");    %Plot signal
                end
            catch
            end
        end

        % Button pushed function: ClearGraphButton
        function ClearGraph(app, event)
            s = size(app.signalPlotFile1.Signal);
            while s(2) > 0
                index = getSignalRowFromName(app, app.signalPlotFile1.ChannelName(1));
                app.signalTableAllFile1{index, app.PLOT_COLUMN_NUMBER} = false;
                delete(app.signalPlotFile1.Signal(1));
                app.signalPlotFile1.Signal(1) = [];
                app.signalPlotFile1.ChannelName(1) = [];
                s = size(app.signalPlotFile1.Signal);
            end
            s = size(app.signalPlotFile2.Signal);
            while s(2) > 0
                index = getSignalRowFromName(app, app.signalPlotFile2.ChannelName(1));
                app.signalTableAllFile2{index, app.PLOT_COLUMN_NUMBER} = false;
                delete(app.signalPlotFile2.Signal(1));
                app.signalPlotFile2.Signal(1) = [];
                app.signalPlotFile2.ChannelName(1) = [];
                s = size(app.signalPlotFile2.Signal);
            end
            s = size(app.signalPlotLap);
            for i=1:s(2)
                d = size(app.signalPlotLap(i).Lap);
                for j=1:d(2)
                    delete(app.signalPlotLap(i).Plot(j));
                end
            end
            app.signalPlotLap = [];
            app.splitSignalLap = [];
            if app.File1Button.Value
                changeDataTableAll(app, "", getSignalTable(app, app.nameFile1));
            elseif app.File2Button.Value
                changeDataTableAll(app, "", getSignalTable(app, app.nameFile2));
            end
            changeDataTablePlot(app);
            app.splitSignalLap = [];
            app.UITableLap.Data = app.splitSignalLap;
            app.fileSelected = "";
            app.nameSelected = "";
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.File1Button.Value
                changeDataTableAll(app, "", getSignalTable(app, app.nameFile1));
                fieldSearchValueChanged(app, app.FieldSearch.Value);
            elseif app.File2Button.Value
                changeDataTableAll(app, "", getSignalTable(app, app.nameFile2));
                fieldSearchValueChanged(app, app.FieldSearch.Value);
            end
        end

        % Callback function: UITablePlot
        function UITablePlotSelectionChanged(app, event)
            try
                selection = app.UITablePlot.Selection;
                signalPlot = get(app.UITablePlot, 'Data');
                app.nameSelected = signalPlot{selection, app.NAME_COLUMN_NUMBER};
                app.fileSelected = signalPlot{selection, 3};
                changeDataTableLap(app);
                if signalPlot{selection, app.PLOT_COLUMN_NUMBER}
                    setYValue(app);
                end
            catch
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            if ~get(app.PanelButtons, 'Visible')
                if strcmp(key, "rightarrow")
                    app.x = app.x + double(app.DeltaEditField.Value);
                    setYValue(app);
                    set(app.XEditField, 'Value', app.x);
                elseif strcmp(key, "leftarrow")
                    app.x = app.x - double(app.DeltaEditField.Value);
                    setYValue(app);
                    set(app.XEditField, 'Value', app.x);
                end
            else
                if strcmp(key, "rightarrow")
                    app.xButtons = app.xButtons + double(app.DeltaEditFieldButtons.Value);
                    setYValueButtons(app);
                    set(app.XEditFieldButtons, 'Value', app.xButtons);
                elseif strcmp(key, "leftarrow")
                    app.xButtons = app.xButtons - double(app.DeltaEditFieldButtons.Value);
                    setYValueButtons(app);
                    set(app.XEditFieldButtons, 'Value', app.xButtons);
                end
            end
        end

        % Cell edit callback: UITableLap
        function UITableLapCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2) == app.PLOT_COLUMN_NUMBER
                if newData
                    plotLap(app, indices(1));
                else
                    s = size(app.signalPlotLap);
                    for i=1:s(2)
                        if strcmp(app.nameSelected, app.signalPlotLap(i).Name) && strcmp(app.fileSelected, app.signalPlotLap(i).File)
                            d = size(app.signalPlotLap(i).Lap);
                            for j=1:d(2)
                                if indices(1) == app.signalPlotLap(i).Lap(j)
                                    set(app.signalPlotLap(i).Plot(j), 'Visible', 'off');
                                end
                            end
                        end
                    end
                end
            elseif indices(2) == 6 || indices(2) == 7 || indices(2) == 8
                table = get(app.UITableLap, 'Data');
                gain = table{indices(1), 6};
                offsetX = table{indices(1), 7};
                offsetY = table{indices(1), 8};
                s = size(app.splitSignalLap);
                indexSplit = 0;
                for i=1:s(2)
                    if strcmp(app.nameSelected, app.splitSignalLap(i).Name) && strcmp(app.fileSelected, app.splitSignalLap(i).File)
                        indexSplit = i;
                        break
                    end
                end
                s = size(app.signalPlotLap);
                for i=1:s(2)
                    if strcmp(app.nameSelected, app.signalPlotLap(i).Name) && strcmp(app.fileSelected, app.signalPlotLap(i).File)
                        d = size(app.signalPlotLap(i).Lap);
                        for j=1:d(2)
                            if indices(1) == app.signalPlotLap(i).Lap(j)
                                set(app.signalPlotLap(i).Plot(j), 'XData', app.splitSignalLap(indexSplit).Laps(indices(1)).X + offsetX, 'YData', app.splitSignalLap(indexSplit).Laps(indices(1)).Y * gain + offsetY);
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: AnalisiGuidaButton
        function AnalisiGuidaButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'on');
            app.buttonClicked = "guida";
            plotGuideSignal(app);
        end

        % Button pushed function: AnalisiCurvaeAmmoButton
        function AnalisiCurvaeAmmoButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'on');
            app.buttonClicked = "curva";
            plotTurnAndShockAbs(app);
        end

        % Button pushed function: AnalisiFrenataButton
        function AnalisiFrenataButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'on');
            app.buttonClicked = "frenata";
            plotBrake(app);
        end

        % Button pushed function: BatteriaButton
        function BatteriaButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'on');
            app.buttonClicked = "batteria";
            plotBattery(app);
        end

        % Button pushed function: MotoreButton
        function MotoreButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'on');
            app.buttonClicked = "motore";
            plotEngine(app);
        end

        % Callback function: UITableButtonsPlot
        function UITableButtonsPlotSelectionChanged(app, event)
            try
                selection = app.UITableButtonsPlot.Selection;
                signalPlot = get(app.UITableButtonsPlot, 'Data');
                app.nameSelectedButtons = signalPlot{selection, app.NAME_COLUMN_NUMBER};
                app.fileSelectedButtons = signalPlot{selection, 3};
                setYValueButtons(app);
            catch
            end
        end

        % Cell edit callback: UITableButtonsPlot
        function UITableButtonsPlotCellEdit(app, event)
            indices = event.Indices; % indice (riga,colonna) della cella seleionata
            newData = event.NewData; % dato cambiato
            name = app.signalTableButtons{indices(1), app.NAME_COLUMN_NUMBER};
            file = app.signalTableButtons{indices(1), 3};
            table = get(app.UITableButtonsPlot, 'Data');
            offsetY = table{indices(1), 9};
            gain = table{indices(1), 7};
            if strcmp(file, app.nameFile1)
                file = 1;
            elseif strcmp(file, app.nameFile2)
                file = 2;
            end
            signal = getSignalFromName(app, name, file);
            if indices(2) == app.PLOT_COLUMN_NUMBER      % controllo se è stato cambiato un valore nella checkbox della visibilità
                if newData == false % se la visibilità è stata impostata a false
                    set(app.signalPlotButtons.Signal(indices(1)), 'Visible', 'off'); % setto la visibilità a off
                else
                    set(app.signalPlotButtons.Signal(indices(1)), 'Visible', 'on'); % setto la visibilità a on
                end
            elseif indices(2) == 7 
                gain = newData;     % Extract user gain
                set(app.signalPlotButtons.Signal(indices(1)), 'YData', signal.signals.values * gain + offsetY);
            elseif indices(2) == 8
                offsetX = newData; % Extract user offset
                set(app.signalPlotButtons.Signal(indices(1)), 'XData', signal.time + offsetX);
            elseif indices(2) == 9
                offsetY = newData; % Extract user offset
                set(app.signalPlotButtons.Signal(indices(1)), 'YData', signal.signals.values * gain + offsetY);
            end
        end

        % Value changed function: CheckBoxFile2
        function CheckBoxFile2ValueChanged(app, event)
            value = app.CheckBoxFile2.Value;
            s = size(app.signalTableButtons);
            if value
                for i=1:s(1)
                    if strcmp(app.signalTableButtons{i, 3}, app.nameFile2)
                        set(app.signalPlotButtons.Signal(i), 'Visible', 'on');
                        app.signalTableButtons{i, app.PLOT_COLUMN_NUMBER} = true;
                    end
                end
            else
                for i=1:s(1)
                    if strcmp(app.signalTableButtons{i, 3}, app.nameFile2)
                        set(app.signalPlotButtons.Signal(i), 'Visible', 'off');
                        app.signalTableButtons{i, app.PLOT_COLUMN_NUMBER} = false;
                    end
                end
            end
            app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

        % Value changed function: CheckBoxFile1
        function CheckBoxFile1ValueChanged(app, event)
            value = app.CheckBoxFile1.Value;
            s = size(app.signalTableButtons);
            if value
                for i=1:s(1)
                    if strcmp(app.signalTableButtons{i, 3}, app.nameFile1)
                        set(app.signalPlotButtons.Signal(i), 'Visible', 'on');
                        app.signalTableButtons{i, app.PLOT_COLUMN_NUMBER} = true;
                    end
                end
            else
                for i=1:s(1)
                    if strcmp(app.signalTableButtons{i, 3}, app.nameFile1)
                        set(app.signalPlotButtons.Signal(i), 'Visible', 'off');
                        app.signalTableButtons{i, app.PLOT_COLUMN_NUMBER} = false;
                    end
                end
            end
            app.UITableButtonsPlot.Data = app.signalTableButtons;
        end

        % Value changed function: XEditFieldButtons
        function XEditFieldButtonsValueChanged(app, event)
            value = app.XEditFieldButtons.Value;
            app.xButtons = double(value);
            setYValueButtons(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            set(app.PanelButtons, 'Visible', 'off');
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.x = double(value);
            setYValue(app);
        end

        % Callback function: UITableLap
        function UITableLapSelectionChanged(app, event)
            selection = app.UITableLap.Selection;
            dataTablePlot = get(app.UITablePlot, 'Data');
            selectionPlot = app.UITablePlot.Selection;
            if ~dataTablePlot{selectionPlot, app.PLOT_COLUMN_NUMBER}
                setYLapValue(app, selection);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.PanelMain = uipanel(app.UIFigure);
            app.UIG_Force = uiaxes(app.PanelMain);
            app.UIG_Force.XTick = [];
            app.UIG_Force.YTick = [];
            app.UITrack = uiaxes(app.PanelMain);
            app.UITrack.XTick = [];
            app.UITrack.YTick = [];
            app.UIAxes = uiaxes(app.PanelMain);
            app.UIPedal = uiaxes(app.PanelMain);
            app.UIPedal.XTick = [];
            app.UIPedal.YTick = [];
            app.LabelAccelerator = uilabel(app.PanelMain);
            app.LabelAccelerator.Text = '0 %';
            app.LabelBrake = uilabel(app.PanelMain);
            app.LabelBrake.Text = '0 %';
            app.ClearGraphButton = uibutton(app.PanelMain, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraph, true);
            app.ClearGraphButton.Text = {'Clear Graph'; ''};
            app.UITablePlot = uitable(app.PanelMain);
            app.UITablePlot.ColumnName = {'Var'; 'Visible'; 'File'; 'Min'; 'Max'; 'Unit of Measure'; 'Gain'; 'OffsetX'; 'OffsetY'};
            app.UITablePlot.RowName = {};
            app.UITablePlot.SelectionType = 'row';
            app.UITablePlot.ColumnEditable = [false true false false false false true true true];
            app.UITablePlot.CellEditCallback = createCallbackFcn(app, @UITablePlotCellEdit, true);
            app.UITablePlot.SelectionChangedFcn = createCallbackFcn(app, @UITablePlotSelectionChanged, true);
            app.UITablePlot.Multiselect = 'off';
            app.AnalisiGuidaButton = uibutton(app.PanelMain, 'push');
            app.AnalisiGuidaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisiGuidaButtonPushed, true);
            app.AnalisiGuidaButton.Text = 'Analisi Guida';
            app.UITableLap = uitable(app.PanelMain);
            app.UITableLap.ColumnName = {'Lap'; 'Visible'; 'LapTime'; 'Min'; 'Max'; 'Gain'; 'OffsetX'; 'OffsetY'};
            app.UITableLap.RowName = {};
            app.UITableLap.SelectionType = 'row';
            app.UITableLap.ColumnEditable = [false true false false false true true true];
            app.UITableLap.CellEditCallback = createCallbackFcn(app, @UITableLapCellEdit, true);
            app.UITableLap.SelectionChangedFcn = createCallbackFcn(app, @UITableLapSelectionChanged, true);
            app.UITableLap.Multiselect = 'off';
            app.AnalisiCurvaeAmmoButton = uibutton(app.PanelMain, 'push');
            app.AnalisiCurvaeAmmoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisiCurvaeAmmoButtonPushed, true);
            app.AnalisiCurvaeAmmoButton.Text = 'Analisi Curva e Ammo';
            app.AnalisiFrenataButton = uibutton(app.PanelMain, 'push');
            app.AnalisiFrenataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalisiFrenataButtonPushed, true);
            app.AnalisiFrenataButton.Text = 'Analisi Frenata';
            app.BatteriaButton = uibutton(app.PanelMain, 'push');
            app.BatteriaButton.ButtonPushedFcn = createCallbackFcn(app, @BatteriaButtonPushed, true);
            app.BatteriaButton.Text = 'Batteria';
            app.MotoreButton = uibutton(app.PanelMain, 'push');
            app.MotoreButton.ButtonPushedFcn = createCallbackFcn(app, @MotoreButtonPushed, true);
            app.MotoreButton.Text = 'Motore';
            app.Steer = uiimage(app.PanelMain);
            app.Steer.ImageSource = 'pngegg.png';
            app.LabelSteer = uilabel(app.PanelMain);
            app.LabelSteer.Text = '0°';
            app.LabelSOC = uilabel(app.PanelMain);
            app.LabelSOC.Text = 'SOC: 50%';
            app.LabelRPM = uilabel(app.PanelMain);
            app.LabelRPM.Text = 'RPM: 5000';
            app.LabelSpeed = uilabel(app.PanelMain);
            app.LabelSpeed.Text = 'Speed: 50 km/h';
            app.Settings = uibutton(app.PanelMain, 'push');
            app.Settings.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.Settings.Text = '=';
            app.YEditFieldLabel = uilabel(app.PanelMain);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PanelMain, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.Editable = 'off';
            app.YEditField_2Label_2 = uilabel(app.PanelMain);
            app.YEditField_2Label_2.Text = 'X';
            app.XEditField = uieditfield(app.PanelMain, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.PanelMain);
            app.EditFieldLabel.Text = 'Δ';
            app.DeltaEditField = uieditfield(app.PanelMain, 'numeric');
            app.DeltaEditField.ValueDisplayFormat = '%.3f';
            app.DeltaEditField.Value = 0.01;
            app.PanelButtons = uipanel(app.UIFigure);
            app.PanelButtons.Visible = 'off';
            app.UIAxesButtons = uiaxes(app.PanelButtons);
            zlabel(app.UIAxesButtons, 'Z')
            app.UIPedalButtons = uiaxes(app.PanelButtons);
            app.UIPedalButtons.XTick = [];
            app.UIPedalButtons.YTick = [];
            app.LabelAcceleratorButtons = uilabel(app.PanelButtons);
            app.LabelAcceleratorButtons.Text = '0 %';
            app.LabelBrakeButtons = uilabel(app.PanelButtons);
            app.LabelBrakeButtons.Text = '0 %';
            app.XEditField_2Label = uilabel(app.PanelButtons);
            app.XEditField_2Label.Text = 'X';
            app.XEditFieldButtons = uieditfield(app.PanelButtons, 'numeric');
            app.XEditFieldButtons.ValueDisplayFormat = '%.3f';
            app.XEditFieldButtons.ValueChangedFcn = createCallbackFcn(app, @XEditFieldButtonsValueChanged, true);
            app.UITableButtonsPlot = uitable(app.PanelButtons);
            app.UITableButtonsPlot.ColumnName = {'Var'; 'Visible'; 'File'; 'Min'; 'Max'; 'Unit of Measure'; 'Gain'; 'OffsetX'; 'OffsetY'};
            app.UITableButtonsPlot.RowName = {};
            app.UITableButtonsPlot.SelectionType = 'row';
            app.UITableButtonsPlot.ColumnEditable = [false true false false false false true true true];
            app.UITableButtonsPlot.CellEditCallback = createCallbackFcn(app, @UITableButtonsPlotCellEdit, true);
            app.UITableButtonsPlot.SelectionChangedFcn = createCallbackFcn(app, @UITableButtonsPlotSelectionChanged, true);
            app.UITableButtonsPlot.Multiselect = 'off';
            app.YEditField_2Label = uilabel(app.PanelButtons);
            app.YEditField_2Label.Text = 'Y';
            app.YEditFieldButtons = uieditfield(app.PanelButtons, 'numeric');
            app.YEditFieldButtons.ValueDisplayFormat = '%.3f';
            app.YEditFieldButtons.Editable = 'off';
            app.DeltaEditFieldButtons = uieditfield(app.PanelButtons, 'numeric');
            app.DeltaEditFieldButtons.ValueDisplayFormat = '%.3f';
            app.DeltaEditFieldButtons.Value = 0.01;
            app.Label_2 = uilabel(app.PanelButtons);
            app.Label_2.Text = 'Δ';
            app.CheckBoxFile2 = uicheckbox(app.PanelButtons);
            app.CheckBoxFile2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxFile2ValueChanged, true);
            app.CheckBoxFile2.Text = 'File2';
            app.CheckBoxFile2.Value = true;
            app.CheckBoxFile1 = uicheckbox(app.PanelButtons);
            app.CheckBoxFile1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxFile1ValueChanged, true);
            app.CheckBoxFile1.Text = 'File1';
            app.CheckBoxFile1.Value = true;
            app.VisibileLabel = uilabel(app.PanelButtons);
            app.VisibileLabel.Text = 'Visibile:';
            app.BackButton = uibutton(app.PanelButtons, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.Panel.ButtonDownFcn = createCallbackFcn(app, @PanelButtonDown, true);
            app.UIAxesPreview = uiaxes(app.Panel);
            app.UITableAll = uitable(app.Panel);
            app.UITableAll.ColumnName = {'Var'; 'Plot Data'; 'Min'; 'Max'; 'Unit of Measure'};
            app.UITableAll.RowName = {};
            app.UITableAll.SelectionType = 'row';
            app.UITableAll.ColumnEditable = [false true false false false];
            app.UITableAll.CellEditCallback = createCallbackFcn(app, @UITableAllCellEdit, true);
            app.UITableAll.SelectionChangedFcn = createCallbackFcn(app, @UITableAllSelectionChanged, true);
            app.UITableAll.Multiselect = 'off';
            app.LoadFile2 = uibutton(app.Panel, 'push');
            app.LoadFile2.ButtonPushedFcn = createCallbackFcn(app, @LoadFile2ButtonPushed, true);
            app.LoadFile2.Text = 'Load File 2';
            app.LoadFile1 = uibutton(app.Panel, 'push');
            app.LoadFile1.ButtonPushedFcn = createCallbackFcn(app, @LoadFile1ButtonPushed, true);
            app.LoadFile1.Text = 'Load File 1';
            app.SearchLabel = uilabel(app.Panel);
            app.SearchLabel.Text = 'Search:';
            app.FieldSearch = uieditfield(app.Panel, 'text');
            app.FieldSearch.ValueChangingFcn = createCallbackFcn(app, @FieldSearchValueChanging, true);
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.File1Button = uiradiobutton(app.ButtonGroup);
            app.File1Button.Text = 'File1';
            app.File1Button.Value = true;
            app.File2Button = uiradiobutton(app.ButtonGroup);
            app.File2Button.Text = 'File 2';
            app.SelectLabel = uilabel(app.Panel);
            app.SelectLabel.Text = 'Select:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataVisualizerPremiumPlus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end